classdef Battleship < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        GridLayout2      matlab.ui.container.GridLayout
        offenceGrid      matlab.ui.control.UIAxes
        deffenceGrid     matlab.ui.control.UIAxes
        YKnob            matlab.ui.control.DiscreteKnob
        XKnob            matlab.ui.control.DiscreteKnob
        coordLabel       matlab.ui.control.Label
        Fire             matlab.ui.control.Button
        Lamp             matlab.ui.control.Lamp
        ArmMissileLabel  matlab.ui.control.Label
        ArmSwitch        matlab.ui.control.ToggleSwitch
        InfoLabel        matlab.ui.control.Label
        BATTLESHIPLabel  matlab.ui.control.Label
        Restart          matlab.ui.control.Button
    end

    properties (Access = private)
        Green = [0 0.7 0];
        Red = [0.7 0 0];
        playerTurn = "It's your turn";
        hit = "Hit";
        miss = "Miss";
        fire = "Fire";
        incoming = "Incoming";
        playerWin = "You defeated the enemy ships";
        compWin = "You have been defeated";
        
        y_coord = 'A';
        x_coord = '1';
        y_map = containers.Map({'A','B','C','D','E','F','G','H','I','J'}, [0,1,2,3,4,5,6,7,8,9]);
        x_map = containers.Map({'1','2','3','4','5','6','7','8','9','10'}, [0,1,2,3,4,5,6,7,8,9]);
            
        playerShips = [];
        playerShots = [];
        
        compShips = [];
        compMovesLeft = [];
        
    end
    
    methods (Access = private)
        % Sets up all variables for a new game
        % Generates the grid and draws the ships
        function setupGame(app)
            app.Restart.Visible = 'off';
            app.ArmSwitch.Enable = 'on';
            app.XKnob.Value = '1';
            app.YKnob.Value = 'A';
            genGrid(app, app.offenceGrid);
            genGrid(app, app.deffenceGrid);
            app.playerShips = genShips(app);
            app.compShips = genShips(app);
            drawShips(app, app.playerShips, app.deffenceGrid);
            app.compMovesLeft = genCompMoves(app);
            changeLabel(app, app.playerTurn);
        end
        
        % Generate a grid with the proper lines and proper labels
        function genGrid(~, grid)
            hold(grid, 'on');
            axis(grid, [0 10 0 10]);
            xticks(grid, .5:1:9.5); % move ticks to center text
            xticklabels(grid, {'1','2','3','4','5','6','7','8','9','10'});
            yticks(grid, 0.5:1:9.5);
            yticklabels(grid, {'A','B','C','D','E','F','G','H','I','J'});
            for i = 0:10 % Generate lines for grid
                line(grid, [0 10], [i i], "color", "black");
                line(grid, [i i], [0 10], "color", "black");
            end
        end
        
        % Color a square at x,y with specific colour
        function colorSquare(~, x, y, color, grid)
            rectangle(grid, 'position', [x y 1 1], 'FaceColor', color);
        end
        
        % Updates the coordinates shown in the label
        function update_coord(app)
            app.coordLabel.Text = [app.y_coord app.x_coord];
        end
        
        % Randomly generates a list ships coordinates
        function ships = genShips(app)
            shipSize = [5 4 3 3 2];
            color = [0.1 0.1 0.1];
            ships = genShipPosition(app, shipSize(1), color);
             for i = 2:5
                 tmp = genShipPosition(app, shipSize(i), [i*.1 i*.1 i*.1]);
                 % Brute force check if new ship position is overlapping
                 while ~isValid(app, ships, tmp)
                     tmp = genShipPosition(app, shipSize(i), [i*.1 i*.1 i*.1]);
                 end
                 ships = [ships tmp];
             end
    
        end
        
        % Helper function to generate positions for a single ship
        function coords = genShipPosition(~, size, color)
            direction = randi(2);
            if direction == 1 % horizontal ship
                x = randi([0 (9 - size)]);
                y = randi([0 9]);
                for i = 1:size
                    coords(i).x = x + i;
                    coords(i).y = y;
                    coords(i).color = color;
                end 
            else % vertical ship
                x = randi([0 9]);
                y = randi([0 (9 - size)]);
                for i = 1:size
                    coords(i).x = x;
                    coords(i).y = y + i;
                    coords(i).color = color;
                end
            end
        end
        
        % Draws the ships on a grid
        function drawShips(~,ships, grid)
            for i = 1:length(ships)
                rectangle(grid, 'position', [ships(i).x ships(i).y 1 1], 'FaceColor', ships(i).color);
            end
        end
        
        % Helper fuunction to check if coordinate exist in list, used to
        % check overlapping ships
        function valid = isValid(~,current_list, new_list)
            for i = 1:length(new_list)
                for j = 1:length(current_list)
                    if current_list(j).x == new_list(i).x && current_list(j).y == new_list(i).y
                        valid = false;
                        return
                    end
                end
            end
            valid = true;
        end
        
        % check if coordinate has been hit yet
        function isValid = inputValid(app) 
            str = ['(' num2str(app.x_map(app.x_coord)) ',' num2str(app.y_map(app.y_coord)) ')'];
            isValid = isempty(strfind(app.playerShots, str));
        end
        
        % generates a list off all possible moves for computer
        function compAvailMoves = genCompMoves(app) 
            index = 1;
            for i = 1:10
                for j= 1:10
                    compAvailMoves(index).x = i - 1;
                    compAvailMoves(index).y = j - 1;
                    index = index + 1;
                end
            end
        end
        
        % Checks if the shot hit a target
        function isHit = checkHit(app)
            for i = 1:length(app.compShips)
                if app.x_map(app.x_coord) == app.compShips(i).x && app.y_map(app.y_coord) == app.compShips(i).y
                    app.compShips(i) = [];
                    isHit = true;
                    return;
                end
            end
            isHit = false;
        end
        
        % helper function to change the info text
        function changeLabel(app, txt)
            app.InfoLabel.Text = txt;
        end
        
        % Checks if there is a winner
        function winner = checkWin(app)
            winner = [];
            if isempty(app.playerShips)
                winner = app.compWin;
            elseif isempty(app.compShips)
                winner = app.playerWin;
            else
                return
            end
            pause(1);
            changeLabel(app, winner);
            app.Restart.Visible = 'on';
        end
        
        % Takes care of the computer opponents turn 
        function computersTurn(app)
            index = randi(length(app.compMovesLeft));
            x = app.compMovesLeft(index).x;
            y = app.compMovesLeft(index).y;
            app.compMovesLeft(index) = [];
            changeLabel(app, app.incoming);
            pause(1);
            isHit = false;
            index = -1;
            for i = 1:length(app.playerShips)
                if x == app.playerShips(i).x && y == app.playerShips(i).y
                    isHit = true;
                    index = i;
                end
            end
            if isHit
                color = app.Green;
                changeLabel(app, app.hit);
                app.playerShips(index) = [];
            else
                color = app.Red;
                changeLabel(app, app.miss);
            end
            colorSquare(app, x, y, color, app.deffenceGrid);
            pause(1);
            if isempty(checkWin(app))
                changeLabel(app, app.playerTurn);
                app.ArmSwitch.Enable = 'on';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setupGame(app);
            changeLabel(app, app.playerTurn);
        end

        % Value changed function: YKnob
        function YKnobValueChanged(app, event)
            value = app.YKnob.Value;
            app.y_coord = char(value);
            app.update_coord();
            app.ArmSwitch.Value = 'Off';
            app.Fire.Enable = 'off';
            app.Lamp.Color = [0.65,0.65,0.65];
        end

        % Value changed function: XKnob
        function XKnobValueChanged(app, event)
            value = app.XKnob.Value;
            app.x_coord = char(value);
            app.update_coord();
            app.ArmSwitch.Value = 'Off';
            app.Fire.Enable = 'off';
            app.Lamp.Color = [0.65,0.65,0.65];
        end

        % Button pushed function: Fire
        function FireButtonPushed(app, event)
            app.Fire.Enable = 'off';
            app.ArmSwitch.Enable = 'off';
            changeLabel(app, app.fire);
            pause(1);
            app.playerShots = [app.playerShots '(' num2str(app.x_map(app.x_coord)) ',' num2str(app.y_map(app.y_coord)) '),'];
            disp(app.playerShots);
            if checkHit(app)
                color = app.Green;
                changeLabel(app, app.hit);
            else
                color = app.Red;
                changeLabel(app, app.miss);
            end
            colorSquare(app, app.x_map(app.x_coord), app.y_map(app.y_coord), color, app.offenceGrid);
            app.ArmSwitch.Value = 'Off';
            app.Lamp.Color = [0.65,0.65,0.65];
            pause(1);
            if isempty(checkWin(app))
                computersTurn(app);
            end
        end

        % Value changed function: ArmSwitch
        function ArmSwitchValueChanged(app, event)
            value = app.ArmSwitch.Value;
            if strcmp(value, 'Armed')
                if inputValid(app)
                    app.Lamp.Color = [0,0.8,0];
                    app.Fire.Enable = 'on';
                else
                    app.Lamp.Color = [0.8,0,0];
                end
            else
                app.Lamp.Color = [0.65,0.65,0.65];
                app.Fire.Enable = 'off';
            end
        end

        % Button pushed function: Restart
        function RestartButtonPushed(app, event)
            cla(app.deffenceGrid);
            cla(app.offenceGrid);
            app.y_coord = 'A';
            app.x_coord = '1';
            app.playerShips = [];
            app.playerShots = [];
            app.compShips = [];
            app.compMovesLeft = [];
            setupGame(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.offenceGrid = uiaxes(app.GridLayout2);
            title(app.offenceGrid, 'Enemy Ships')
            xlabel(app.offenceGrid, '')
            ylabel(app.offenceGrid, '')
            app.deffenceGrid = uiaxes(app.GridLayout2);
            title(app.deffenceGrid, 'Your Ships')
            xlabel(app.deffenceGrid, '')
            ylabel(app.deffenceGrid, '')
            app.YKnob = uiknob(app.GridLayout, 'discrete');
            app.YKnob.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J'};
            app.YKnob.ValueChangedFcn = createCallbackFcn(app, @YKnobValueChanged, true);
            app.YKnob.Value = 'A';
            app.XKnob = uiknob(app.GridLayout, 'discrete');
            app.XKnob.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.XKnob.ValueChangedFcn = createCallbackFcn(app, @XKnobValueChanged, true);
            app.XKnob.Value = '1';
            app.coordLabel = uilabel(app.GridLayout);
            app.coordLabel.Text = 'A1';
            app.Fire = uibutton(app.GridLayout, 'push');
            app.Fire.ButtonPushedFcn = createCallbackFcn(app, @FireButtonPushed, true);
            app.Fire.Enable = 'off';
            app.Fire.Text = 'FIRE';
            app.Lamp = uilamp(app.GridLayout);
            app.ArmMissileLabel = uilabel(app.GridLayout);
            app.ArmMissileLabel.Text = 'Arm Missile';
            app.ArmSwitch = uiswitch(app.GridLayout, 'toggle');
            app.ArmSwitch.Items = {'Off', 'Armed'};
            app.ArmSwitch.ValueChangedFcn = createCallbackFcn(app, @ArmSwitchValueChanged, true);
            app.InfoLabel = uilabel(app.GridLayout);
            app.InfoLabel.Text = '';
            app.BATTLESHIPLabel = uilabel(app.GridLayout);
            app.BATTLESHIPLabel.Text = 'BATTLESHIP';
            app.Restart = uibutton(app.GridLayout, 'push');
            app.Restart.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.Restart.Text = 'New Game';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Battleship

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end